classdef ImageApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadImageMenu                matlab.ui.container.Menu
        SaveImageMenu_2              matlab.ui.container.Menu
        FunctionsMenu                matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        GeneralTab                   matlab.ui.container.Tab
        GrayRawImgAxis               matlab.ui.control.UIAxes
        Histogram                    matlab.ui.control.UIAxes
        ImageSizeLabel               matlab.ui.control.Label
        ImageSize                    matlab.ui.control.EditField
        ImageMeanEditFieldLabel      matlab.ui.control.Label
        ImageMean                    matlab.ui.control.EditField
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        StandardDeviation            matlab.ui.control.EditField
        GrayLevelsTab                matlab.ui.container.Tab
        ReduceGrayLevelButton        matlab.ui.control.Button
        ShowInFigure                 matlab.ui.control.CheckBox
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes6                      matlab.ui.control.UIAxes
        UIAxes7                      matlab.ui.control.UIAxes
        UIAxes8                      matlab.ui.control.UIAxes
        ResizeTab                    matlab.ui.container.Tab
        TimesEditFieldLabel          matlab.ui.control.Label
        TimesEditField               matlab.ui.control.NumericEditField
        UIAxes18                     matlab.ui.control.UIAxes
        UIAxes18_2                   matlab.ui.control.UIAxes
        UIAxes18_3                   matlab.ui.control.UIAxes
        IntensityTransformationTab   matlab.ui.container.Tab
        UIAxes9                      matlab.ui.control.UIAxes
        UIAxes10                     matlab.ui.control.UIAxes
        TransformationsButtonGroup   matlab.ui.container.ButtonGroup
        NegativeButton               matlab.ui.control.RadioButton
        LogrithmButton               matlab.ui.control.RadioButton
        PowerLawButton               matlab.ui.control.RadioButton
        PiecewiselinearButton        matlab.ui.control.RadioButton
        HistogramButton              matlab.ui.control.RadioButton
        Label                        matlab.ui.control.Label
        PowerLawY                    matlab.ui.control.NumericEditField
        vEditFieldLabel              matlab.ui.control.Label
        LogV                         matlab.ui.control.NumericEditField
        Piecewise_ay                 matlab.ui.control.NumericEditField
        aLabel                       matlab.ui.control.Label
        Piecewise_ax                 matlab.ui.control.NumericEditField
        Piecewise_bx                 matlab.ui.control.NumericEditField
        Piecewise_by                 matlab.ui.control.NumericEditField
        bLabel                       matlab.ui.control.Label
        TransformButton              matlab.ui.control.Button
        SDFilteringTab               matlab.ui.container.Tab
        FilterTypeDropDownLabel      matlab.ui.control.Label
        FilterTypeDropDown           matlab.ui.control.DropDown
        SDFilterButton               matlab.ui.control.Button
        SmoothingPanel               matlab.ui.container.Panel
        AverageCheckBox              matlab.ui.control.CheckBox
        MedianCheckBox               matlab.ui.control.CheckBox
        GaussianCheckBox             matlab.ui.control.CheckBox
        ContraharmCheckBox           matlab.ui.control.CheckBox
        AverageMask1                 matlab.ui.control.NumericEditField
        AverageMask2                 matlab.ui.control.NumericEditField
        AverageMask3                 matlab.ui.control.NumericEditField
        MedianMask1                  matlab.ui.control.NumericEditField
        MedianMask2                  matlab.ui.control.NumericEditField
        MedianMask3                  matlab.ui.control.NumericEditField
        GaussianSigma1               matlab.ui.control.NumericEditField
        GaussianSigma2               matlab.ui.control.NumericEditField
        GaussianSigma3               matlab.ui.control.NumericEditField
        ContraharmMask1              matlab.ui.control.NumericEditField
        ContraharmMask2              matlab.ui.control.NumericEditField
        ContraharmMask3              matlab.ui.control.NumericEditField
        QEditFieldLabel              matlab.ui.control.Label
        ContraharmQ                  matlab.ui.control.NumericEditField
        MaskLabel                    matlab.ui.control.Label
        MaskLabel_2                  matlab.ui.control.Label
        MaskLabel_3                  matlab.ui.control.Label
        SigmaLabel                   matlab.ui.control.Label
        SharpeningPanel              matlab.ui.container.Panel
        LaplacianCheckBox            matlab.ui.control.CheckBox
        PrewittCheckBox              matlab.ui.control.CheckBox
        SobelCheckBox                matlab.ui.control.CheckBox
        LaplacianAlpha1              matlab.ui.control.NumericEditField
        LaplacianAlpha2              matlab.ui.control.NumericEditField
        LaplacianAlpha3              matlab.ui.control.NumericEditField
        AlphaLabel                   matlab.ui.control.Label
        UIAxes12                     matlab.ui.control.UIAxes
        UIAxes13                     matlab.ui.control.UIAxes
        UIAxes14                     matlab.ui.control.UIAxes
        UIAxes15                     matlab.ui.control.UIAxes
        FDFilteringTab               matlab.ui.container.Tab
        FilterTypeDropDown_2Label    matlab.ui.control.Label
        FilterTypeDropDown_2         matlab.ui.control.DropDown
        FDFilterButton               matlab.ui.control.Button
        SmoothingPanel_2             matlab.ui.container.Panel
        IdealLowPassCheckBox         matlab.ui.control.CheckBox
        GaussianLowPassCheckBox      matlab.ui.control.CheckBox
        ButterworthLowPassCheckBox   matlab.ui.control.CheckBox
        D0EditFieldLabel             matlab.ui.control.Label
        IdealLowPassD0               matlab.ui.control.NumericEditField
        D0EditField_2Label           matlab.ui.control.Label
        GaussianLowPassD0            matlab.ui.control.NumericEditField
        D0EditField_3Label           matlab.ui.control.Label
        ButterworthLowPassD0         matlab.ui.control.NumericEditField
        nEditFieldLabel              matlab.ui.control.Label
        ButterworthLowPassn          matlab.ui.control.NumericEditField
        SharpeningPanel_2            matlab.ui.container.Panel
        IdealHighPassCheckBox        matlab.ui.control.CheckBox
        GaussianHighPassCheckBox     matlab.ui.control.CheckBox
        ButterworthHighPassCheckBox  matlab.ui.control.CheckBox
        D0EditField_4Label           matlab.ui.control.Label
        IdealHighPassD0              matlab.ui.control.NumericEditField
        D0EditField_5Label           matlab.ui.control.Label
        GaussianHighPassD0           matlab.ui.control.NumericEditField
        D0EditField_6Label           matlab.ui.control.Label
        ButterworthHighPassD0        matlab.ui.control.NumericEditField
        nEditField_2Label            matlab.ui.control.Label
        ButterworthHighPassn         matlab.ui.control.NumericEditField
        UIAxes16                     matlab.ui.control.UIAxes
        UIAxes16_2                   matlab.ui.control.UIAxes
        UIAxes16_3                   matlab.ui.control.UIAxes
        UIAxes16_4                   matlab.ui.control.UIAxes
        NoiseReductionTab            matlab.ui.container.Tab
        NoiseReductionPanel          matlab.ui.container.Panel
        ArithmeticCheckBox           matlab.ui.control.CheckBox
        GeometricCheckBox            matlab.ui.control.CheckBox
        ContraharmonicCheckBox       matlab.ui.control.CheckBox
        OrderStatisticsCheckBox      matlab.ui.control.CheckBox
        AdaptivefiltersCheckBox      matlab.ui.control.CheckBox
        MaskEditFieldLabel           matlab.ui.control.Label
        ArithmeticMask1              matlab.ui.control.NumericEditField
        MaskEditField_2Label         matlab.ui.control.Label
        GeometricMask1               matlab.ui.control.NumericEditField
        MaskEditField_3Label         matlab.ui.control.Label
        ContraHarmonicMask1          matlab.ui.control.NumericEditField
        MaskEditField_4Label         matlab.ui.control.Label
        OrderStatisticsMask          matlab.ui.control.NumericEditField
        OrderEditFieldLabel          matlab.ui.control.Label
        OrderStatisticsOrder         matlab.ui.control.NumericEditField
        QEditFieldLabel_2            matlab.ui.control.Label
        ContraHarmonicQ              matlab.ui.control.NumericEditField
        NoiseReduceButton            matlab.ui.control.Button
        UIAxes17                     matlab.ui.control.UIAxes
        UIAxes17_2                   matlab.ui.control.UIAxes
        UIAxes17_3                   matlab.ui.control.UIAxes
        UIAxes17_4                   matlab.ui.control.UIAxes
        NoiseadditionPanel           matlab.ui.container.Panel
        GaussianNoiseCheckBox        matlab.ui.control.CheckBox
        meanEditFieldLabel           matlab.ui.control.Label
        GaussianNoisemean            matlab.ui.control.NumericEditField
        varianceEditFieldLabel       matlab.ui.control.Label
        GaussianNoiseVar             matlab.ui.control.NumericEditField
        SaltpepperNoiseCheckBox      matlab.ui.control.CheckBox
        PaLabel                      matlab.ui.control.Label
        SaltPepperPa                 matlab.ui.control.NumericEditField
        RayleighNoiseCheckBox        matlab.ui.control.CheckBox
        ExponentialNoiseCheckBox     matlab.ui.control.CheckBox
        ErlangNoiseCheckBox          matlab.ui.control.CheckBox
        aEditFieldLabel              matlab.ui.control.Label
        RayleighNoiseA               matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        RayleighNoiseB               matlab.ui.control.NumericEditField
        aEditField_2Label            matlab.ui.control.Label
        ExponentialNoiseA            matlab.ui.control.NumericEditField
        aEditField_3Label            matlab.ui.control.Label
        ErlangNoiseA                 matlab.ui.control.NumericEditField
        bEditField_2Label            matlab.ui.control.Label
        ErlangNoiseB                 matlab.ui.control.NumericEditField
        PbLabel                      matlab.ui.control.Label
        SaltPepperPb                 matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        GrayRawImage % Description
    end
    
    properties (Access = private)
        ImageRowNum % Description
        ImageColumnNum % Description
    end
    
    methods (Access = private)
        
        function results = LogParanumberChanged(app,numfld)
            v=numfld.EditField;
            f = mat2gray(app.GrayRawImage);
            g_1 = log2(1 + v*f)/log2(v+1);
            imshow(g_1,[0 1],'parent',app.UIAxes10);
        end
        
        function results = PowerLawTransform(app)
            
            f = mat2gray(app.GrayRawImage,[0 255]);
            C = 1;
            Gamma = app.PowerLawY.Value;
            g = C*(f.^Gamma);
            imshow(g,[0 1],'parent',app.UIAxes10);
            
        end
        
        function results = LogTransform(app)
            v=app.LogV.Value;
            f = mat2gray(app.GrayRawImage);
            g = log2(1 + v*f)/log2(v+1);
            imshow(g,[0 1],'parent',app.UIAxes10);
        end
        
        function results = PieceWiseLinearTransform(app)
            f0=0;g0=0;
            f1=app.Piecewise_ax.Value; g1=app.Piecewise_ay.Value;
            f2=app.Piecewise_bx.Value; g2=app.Piecewise_by.Value;
            f3=255;g3=255;
            plot(app.UIAxes9,[f0,f1,f2,f3],[g0,g1,g2,g3]); 
            
            r1=(g1-g0)/(f1-f0);
            b1=g0-r1*f0;
            r2=(g2-g1)/(f2-f1);
            b2=g1-r2*f1;
            r3=(g3-g2)/(f3-f2);
            b3=g2-r3*f2;
            [m,n]=size(app.GrayRawImage);
            X2=double(app.GrayRawImage);
            for i=1:m
                for j=1:n
                    f=X2(i,j);
                    g(i,j)=0;
                    if(f>=f1)&(f<=f2)
                        g(i,j)=r1*f+b2;
                    elseif(f>=f2)&(f<=f3)
                        g(i,j)=r3*f+b3;
                    end
                end
            end

            imshow(mat2gray(g),[0 1],'parent',app.UIAxes10);
            
        end
        
        function imt = ReduceGrayLevel(app,img, factor)
            if factor < 0
                factor = 0;
            elseif factor > 8
                factor = 8;    
            end
            
            dfact = uint8(power(2,factor));
            imt = floor((img ./ dfact)) * dfact;
        end
        
        function imt = change_pixels(app,ima,factor)
            
            % 功能： 基于像素复制方式进行图像缩放
            % 输入参数为一整数缩放因子
            % 当缩放因子为负整数时代表缩小，正整数时代表放大
            f=round(factor);
            % wH=1; wL=2;
            [xs,ys] = size(ima);
            if f<0               %缩放因子小于0时，判断为缩小图像
                fa = abs(f);       %取绝对值
                %imt = zeros((xs/fa),(xs/fa));  
                for i=1:(ceil(xs/fa))
                    for j=1:(ceil(ys/fa))
                        imt(i,j) = ima(i*fa,j*fa);
                    end
                end
            elseif f>0           %缩放因子大于0时，判断为放大图像
                    for i=1:(xs*f)
                        for j=1:(ys*f)
                            imt(i,j) = ima(ceil(i/f),ceil(j/f));  
            % round(x)：四舍五入至最近整数  
            % fix(x)：无论正负，舍去小数至最近整数 
            % floor(x)：下取整，即舍去正小数至最近整数  
            % ceil(x)：上取整，即加入正小数至最近整数
                        end
                    end
            else 
                imt = ima;
            end
        end
        
        function reformedimage = ContraHarmFiltering(app, img, Q, masksize)
            data=im2double(img);
            
            pixln=0;
            pixld=0;
            [ro,col]=size(data);
            reformedimage = zeros(ro, col);
            %% Main Module for Contra Harmonic Mean Filter
            for i=1:ro
                for j=1:col
                    for m=-masksize:masksize
                        for n=-masksize:masksize
                            if (i+m>0 && i+m<ro && j+n>0 && j+n<col && ...      % To keep indices in limit
                                    masksize+m>0 && masksize+m<ro && ...
                                    masksize+n>0 && masksize+n<col) 
                                
                                pixl1=(data(i+m,j+n)).^(Q+1);                   % Application of Formula
                                pixl2= (data(i+m,j+n)).^Q;
                                pixln=pixln+pixl1;                              % Application of Summation
                                pixld=pixld+pixl2;
                            
                            end
                        end
                    end
                   reformedimage(i,j)=(pixln/pixld);
                   pixln=0;
                   pixld=0;
                end
            end            
        end
        
        function [SelectionNum, SelectedFunc] = CheckSDFilteringFuncSelection(app)
            SelectionNum = app.AverageCheckBox.Value + app.MedianCheckBox.Value + app.GaussianCheckBox.Value + app.ContraharmCheckBox.Value + ...
                           app.LaplacianCheckBox.Value + app.SobelCheckBox.Value + app.PrewittCheckBox.Value;
            SelectedFunc = 0;
            if(SelectionNum == 1)
                if(app.AverageCheckBox.Value == 1)
                    SelectedFunc = 'Average';
                elseif (app.MedianCheckBox.Value == 1)
                    SelectedFunc = 'Median';
                elseif (app.GaussianCheckBox.Value == 1)
                    SelectedFunc = 'Gaussian';
                elseif (app.ContraharmCheckBox.Value == 1)
                    SelectedFunc = 'ContraHarm';
                elseif (app.LaplacianCheckBox.Value == 1)
                    SelectedFunc = 'Laplacian';
                elseif (app.SobelCheckBox.Value == 1)
                    SelectedFunc = 'Sobel';
                elseif (app.PrewittCheckBox.Value == 1)
                    SelectedFunc = 'Prewitt';
                end
            end
        end
        
        
        function [SelectionNum, SelectedFunc] = CheckFDFilteringFuncSelection(app)
            SelectionNum = app.IdealLowPassCheckBox.Value + app.GaussianLowPassCheckBox.Value + app.ButterworthLowPassCheckBox.Value + ...
                           app.IdealHighPassCheckBox.Value + app.GaussianHighPassCheckBox.Value + app.ButterworthHighPassCheckBox.Value;
            SelectedFunc = 0;
            if(SelectionNum == 1)
                if(app.IdealLowPassCheckBox.Value == 1)
                    SelectedFunc = 'IdealLowPass';
                elseif (app.GaussianLowPassCheckBox.Value == 1)
                    SelectedFunc = 'GaussianLowPass';
                elseif (app.ButterworthLowPassCheckBox.Value == 1)
                    SelectedFunc = 'ButterworthLowPass';
                elseif (app.IdealHighPassCheckBox.Value == 1)
                    SelectedFunc = 'IdealHighPass';
                elseif (app.GaussianHighPassCheckBox.Value == 1)
                    SelectedFunc = 'GaussianHighPass';
                elseif (app.ButterworthHighPassCheckBox.Value == 1)
                    SelectedFunc = 'ButterworthHighPass';
                end
            end
        end
        
        function [SelectionNum, SelectedFunc] = CheckNoiseReductionFuncSelection(app)
            SelectionNum = app.ArithmeticCheckBox.Value + app.GeometricCheckBox.Value + app.ContraharmonicCheckBox.Value + ...
                           app.OrderStatisticsCheckBox.Value + app.AdaptivefiltersCheckBox.Value;
            SelectedFunc = 0;
            if(SelectionNum == 1)
                if(app.ArithmeticCheckBox.Value == 1)
                    SelectedFunc = 'Arithmetic';
                elseif (app.GeometricCheckBox.Value == 1)
                    SelectedFunc = 'Geometric';
                elseif (app.ContraharmonicCheckBox.Value == 1)
                    SelectedFunc = 'Contraharmonic';
                elseif (app.OrderStatisticsCheckBox.Value == 1)
                    SelectedFunc = 'OrderStatisticsfilters';
                elseif (app.AdaptivefiltersCheckBox.Value == 1)
                    SelectedFunc = 'Adaptivefilters';
                end
            end
        end
        
        function f = GeometricMeanFiltering(app,g,m,n)
            g = im2double(g);
            f = exp(imfilter(log(g),ones(m,n),'replicate')).^(1/m/n);
        end
        
        function filterImg = AdaptiveMedianFiltering(app,img)
            [Im,In] = size(img);
            nmin = 3;
            nmax = 9;
            Imf = img;
            I_ex = [zeros((nmax-1)/2,In+(nmax-1));zeros(Im,(nmax-1)/2),img,zeros(Im,(nmax-1)/2);zeros((nmax-1)/2,In+(nmax-1))];
            for x = 1:Im     
                for y = 1:In          
                    for n = nmin:2:nmax                                
                      Sxy = I_ex(x+(nmax-1)/2-(n-1)/2:x+(nmax-1)/2+(n-1)/2,y+(nmax-1)/2-(n-1)/2:y+(nmax-1)/2+(n-1)/2);                  
                        Smax = max(max(Sxy));%求出窗口内像素的最大值                 
                        Smin = min(min(Sxy));%求出窗口内像素的最小值               
                        Smed = median(median(Sxy));%求出窗口内像素的中值              
                        %判断中值是否是噪声点              
                        if Smed > Smin && Smed < Smax                
                            %若中值既大于最小值又小于最大值，则不是             
                            %是，则退出该if语句，增大窗口尺寸，再次判断            
                            %不是，则判断该点的原值是不是噪声点                 
                            if Imf(x,y) <= Smin || Imf(x,y) >= Smax                      
                                %若该点的原值既大于最小值又小于最大值，则不是                  
                                %不是，则输出原值，即不作处理                
                                %是，则输出中值                  
                                Imf(x,y) = Smed;                 
                            end
                            break%有输出则不再进行循环判断             
                        end
                    end
                    %当n=max时，输出中值      
                    Imf(x,y) = Smed;   
                end
            end
            filterImg = Imf;
        end
        
        function filteredImg = ArithmeticMeanFilter(app, img)
            a=ones(n);
            [M,N]=size(x);
            x1=double(x);
            x2=x1;
            for i=1:M-n+1
                 for j=N-n+1
                        c=x1(i:i+n-1,j:j+n-1).*a;
                        s=sum(sum(c));
                        x2(i+fix((n-1)/2),j+fix((n-1)/2))=s/(n*n);  
                 end
            end
        end
        
        function R = imnoise2(app,type, M, N, a, b)
            %IMNOISE2 Generates an array of random numbers with specified PDF.
            %   R = IMNOISE2(TYPE, M, N, A, B) generates an array, R, of size
            %   M-by-N, whose elements are random numbers of the specified TYPE
            %   with parameters A and B. If only TYPE is included in the
            %   input argument list, a  single random number of the specified
            %   TYPE and default parameters shown below is generated. If only
            %   TYPE, M, and N are provided, the default parameters shown below
            %   are used.  If M = N = 1, IMNOISE2 generates a single random
            %   number of the specified TYPE and parameters A and B.
            %
            %   Valid values for TYPE and parameters A and B are:
            % 
            %   'uniform'       Uniform random numbers in the interval (A, B).
            %                   The default values are (0, 1).  
            %   'gaussian'      Gaussian random numbers with mean A and standard
            %                   deviation B. The default values are A = 0, B = 1.
            %   'salt & pepper' Salt and pepper numbers of amplitude 0 with
            %                   probability Pa = A, and amplitude 1 with
            %                   probability Pb = B. The default values are Pa =
            %                   Pb = A = B = 0.05.  Note that the noise has
            %                   values 0 (with probability Pa = A) and 1 (with
            %                   probability Pb = B), so scaling is necessary if
            %                   values other than 0 and 1 are required. The noise
            %                   matrix R is assigned three values. If R(x, y) =
            %                   0, the noise at (x, y) is pepper (black).  If
            %                   R(x, y) = 1, the noise at (x, y) is salt
            %                   (white). If R(x, y) = 0.5, there is no noise
            %                   assigned to coordinates (x, y). 
            %   'lognormal'     Lognormal numbers with offset A and shape
            %                   parameter B. The defaults are A = 1 and B =
            %                   0.25.
            %   'rayleigh'      Rayleigh noise with parameters A and B. The
            %                   default values are A = 0 and B = 1. 
            %   'exponential'   Exponential random numbers with parameter A.  The
            %                   default is A = 1.
            %   'erlang'        Erlang (gamma) random numbers with parameters A
            %                   and B.  B must be a positive integer. The
            %                   defaults are A = 2 and B = 5.  Erlang random
            %                   numbers are approximated as the sum of B
            %                   exponential random numbers.
            
            %   Copyright 2002-2004 R. C. Gonzalez, R. E. Woods, & S. L. Eddins
            %   Digital Image Processing Using MATLAB, Prentice-Hall, 2004
            %   $Revision: 1.5 $  $Date: 2003/10/12 23:37:29 $
            
            % Set default values.
            if nargin == 1
               a = 0; b = 1;
               M = 1; N = 1;
            elseif nargin == 3
               a = 0; b = 1;
            end
            
            % Begin processing. Use lower(type) to protect against input being
            % capitalized. 
            switch lower(type)
            case 'uniform'
               R = a + (b - a)*rand(M, N);
            case 'gaussian'
               R = a + b*randn(M, N);
            case 'salt & pepper'
               if nargin <= 3
                  a = 0.05; b = 0.05;
               end
               % Check to make sure that Pa + Pb is not > 1.
               if (a + b) > 1
                  error('The sum Pa + Pb must not exceed 1.')
               end
               R(1:M, 1:N) = 0.5;
               % Generate an M-by-N array of uniformly-distributed random numbers
               % in the range (0, 1). Then, Pa*(M*N) of them will have values <=
               % a. The coordinates of these points we call 0 (pepper
               % noise). Similarly, Pb*(M*N) points will have values in the range
               % > a & <= (a + b).  These we call 1 (salt noise). 
               X = rand(M, N);
               c = find(X <= a);
               R(c) = 0;
               u = a + b;
               c = find(X > a & X <= u);
               R(c) = 1;
            case 'lognormal'
               if nargin <= 3
                  a = 1; b = 0.25;
               end
               R = a*exp(b*randn(M, N));
            case 'rayleigh'
               R = a + (-b*log(1 - rand(M, N))).^0.5;
            case 'exponential'
               if nargin <= 3
                  a = 1;
               end
               if a <= 0
                  error('Parameter a must be positive for exponential type.')
               end
               k = -1/a;
               R = k*log(1 - rand(M, N));
            case 'erlang'
               if nargin <= 3
                  a = 2; b = 5;
               end
               if (b ~= round(b) | b <= 0)
                  error('Param b must be a positive integer for Erlang.')
               end
               k = -1/a;
               R = zeros(M, N);
               for j = 1:b         
                  R = R + k*log(1 - rand(M, N));
               end
            otherwise
               error('Unknown distribution type.')
            end
        end
        
        function [SelectionNum,SelectedFunc] = CheckNoiseAdditionFunction(app)
            SelectionNum = app.GaussianNoiseCheckBox.Value + app.SaltpepperNoiseCheckBox.Value +  ...
                           app.RayleighNoiseCheckBox.Value + app.ExponentialNoiseCheckBox.Value + app.ErlangNoiseCheckBox.Value;
            SelectedFunc = 0;
            if(SelectionNum == 1)
                if(app.GaussianNoiseCheckBox.Value == 1)
                    SelectedFunc = 'GaussianNoise';
                elseif (app.SaltpepperNoiseCheckBox.Value == 1)
                    SelectedFunc = 'SaltpepperNoise';
                elseif (app.RayleighNoiseCheckBox.Value == 1)
                    SelectedFunc = 'RayleighNoise';
                elseif (app.ExponentialNoiseCheckBox.Value == 1)
                    SelectedFunc = 'ExponentialNoise';
                elseif (app.ErlangNoiseCheckBox.Value == 1)
                    SelectedFunc = 'ErlangNoise';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            
            imgpath='original.jpg';
            %Read the image data
            rawimg=imread(imgpath);
            
            %Change to gray image and store it to public variable
            app.GrayRawImage = rgb2gray(rawimg);
            
            % Remove title, axis labels, and tick labels
            title(app.GrayRawImgAxis, []);
            xlabel(app.GrayRawImgAxis, []);
            ylabel(app.GrayRawImgAxis, []);
            app.GrayRawImgAxis.XAxis.TickLabels = {};
            app.GrayRawImgAxis.YAxis.TickLabels = {};
            imshow(app.GrayRawImage,'parent',app.GrayRawImgAxis, ...
                   'XData', [1 app.GrayRawImgAxis.Position(3)], ...
                   'YData', [1 app.GrayRawImgAxis.Position(4)]);
            
            histogram(app.Histogram, app.GrayRawImage,[0:1:255]);
            
            [row,column]=size(app.GrayRawImage);
            app.ImageRowNum = row;
            app.ImageColumnNum = column;
            imgsize = strcat(int2str(row),'*',int2str(column));
            app.ImageSize.Value=imgsize;
            app.ImageMean.Value = num2str(mean2(app.GrayRawImage));
            app.StandardDeviation.Value=num2str(std2(app.GrayRawImage));


        end

        % Menu selected function: LoadImageMenu
        function LoadImageMenuSelected(app, event)
           %Select a image file 
            [filename,pathname]=uigetfile( ...
            {'*.jpg;*.png;*.bmp;*.gif;*.tif', ...
                'image files (*.jpg,*.png,*.bmp,*.gif,*.tif)'; ...
                '*.*', 'All Files (*.*)'}, ...
                'Please select an image.');
            if isequal(filename,0) || isequal(pathname,0)
                return;
            else
                imgpath=[pathname,filename];
            end
            %Create axes graphics object
            %Read the image data
            app.GrayRawImage=imread(imgpath);
            
            mysize=size(app.GrayRawImage);
            if numel(mysize)>2
                %Change to gray image and store it to public variable
                app.GrayRawImage = rgb2gray(app.GrayRawImage);
            end
            
            imshow(app.GrayRawImage,'parent',app.GrayRawImgAxis);

            
            histr = histogram(app.Histogram, app.GrayRawImage,[0:1:255]);
            
            [row,column]=size(app.GrayRawImage);
            imgsize = strcat(int2str(row),'*',int2str(column));
            app.ImageSize.Value=imgsize;
            app.ImageMean.Value = num2str(mean2(app.GrayRawImage));
            app.StandardDeviation.Value=num2str(std2(app.GrayRawImage));
            
        end

        % Button pushed function: ReduceGrayLevelButton
        function ReduceGrayLevelButtonPushed(app, event)

            if(app.ShowInFigure.Value == 0)
                imshow(ReduceGrayLevel(app,app.GrayRawImage,1),'parent',app.UIAxes);
                app.UIAxes.Title.String ='256 Levles';
                imshow(ReduceGrayLevel(app,app.GrayRawImage,2),'parent',app.UIAxes2);
                app.UIAxes2.Title.String ='128 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage,3),'parent',app.UIAxes3);
                app.UIAxes3.Title.String ='64 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage,4),'parent',app.UIAxes4);
                app.UIAxes4.Title.String ='32 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage,5),'parent',app.UIAxes5);
                app.UIAxes5.Title.String ='16 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage,6),'parent',app.UIAxes6);
                app.UIAxes6.Title.String ='8 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage,7),'parent',app.UIAxes7);
                app.UIAxes7.Title.String ='4 Levels';
                imshow(ReduceGrayLevel(app,app.GrayRawImage,8),'parent',app.UIAxes8);
                app.UIAxes8.Title.String ='2 Levels';
            elseif (app.ShowInFigure.Value == 1)
                subplot(2,4,1), imshow(ReduceGrayLevel(app,app.GrayRawImage,1),[]), title('256 Levles');
                subplot(2,4,2), imshow(ReduceGrayLevel(app,app.GrayRawImage,2),[]), title('128 Levles');
                subplot(2,4,3), imshow(ReduceGrayLevel(app,app.GrayRawImage,3),[]), title('64 Levles');
                subplot(2,4,4), imshow(ReduceGrayLevel(app,app.GrayRawImage,4),[]), title('32 Levles');
                subplot(2,4,5), imshow(ReduceGrayLevel(app,app.GrayRawImage,5),[]), title('16 Levles');
                subplot(2,4,6), imshow(ReduceGrayLevel(app,app.GrayRawImage,6),[]), title('8 Levles');
                subplot(2,4,7), imshow(ReduceGrayLevel(app,app.GrayRawImage,7),[]), title('4 Levles');
                subplot(2,4,8), imshow(ReduceGrayLevel(app,app.GrayRawImage,8),[]), title('2 Levles');
            end
            
        end

        % Value changed function: Piecewise_ax
        function Piecewise_axValueChanged(app, event)
            if (app.TransformationsButtonGroup.SelectedObject.Text == 'Piecewise-linear')
                PieceWiseLinearTransform(app);
            end
                                                 
        end

        % Value changed function: Piecewise_ay
        function Piecewise_ayValueChanged(app, event)
            if (app.TransformationsButtonGroup.SelectedObject.Text == 'Piecewise-linear')
                PieceWiseLinearTransform(app);
            end
                        
        end

        % Value changed function: Piecewise_bx
        function Piecewise_bxValueChanged(app, event)
            if (app.TransformationsButtonGroup.SelectedObject.Text == 'Piecewise-linear')
                PieceWiseLinearTransform(app);
            end
            
        end

        % Value changed function: Piecewise_by
        function Piecewise_byValueChanged(app, event)
            if (app.TransformationsButtonGroup.SelectedObject.Text == 'Piecewise-linear')
                PieceWiseLinearTransform(app);
            end
            
        end

        % Value changed function: TimesEditField
        function TimesEditFieldValueChanged(app, event)
            value = app.TimesEditField.Value;
            sized_img1 = imresize(im2double(app.GrayRawImage),value,'Method',"nearest");
            
            imshow(sized_img1, 'parent',app.UIAxes18);
            [m,n] = size(sized_img1);
            app.UIAxes18.Title.String = strcat('Nearest-','Size:',num2str(n),'X',num2str(m));
            
            sized_img2 = imresize(im2double(app.GrayRawImage),value,'Method',"bilinear");
            imshow(sized_img2, 'parent',app.UIAxes18_2);
            [m,n] = size(sized_img2);
            app.UIAxes18_2.Title.String = strcat('Bilinear-','Size:',num2str(n),'X',num2str(m));
            
            sized_img3 = imresize(im2double(app.GrayRawImage),value,'Method',"bicubic");
            imshow(sized_img3, 'parent',app.UIAxes18_3);
            [m,n] = size(sized_img3);
            app.UIAxes18_3.Title.String = strcat('Bicubic-','Size:',num2str(n),'X',num2str(m));

        end

        % Button pushed function: SDFilterButton
        function SDFilterButtonPushed(app, event)
            [SelectionNum, SelectedFunc] = CheckSDFilteringFuncSelection(app);
            
            if (SelectionNum == 1)
                switch SelectedFunc
                    case 'Average'
                        h1=fspecial('average',[app.AverageMask1.Value,app.AverageMask1.Value]);
                        h2=fspecial('average',[app.AverageMask2.Value,app.AverageMask2.Value]);
                        h3=fspecial('average',[app.AverageMask3.Value,app.AverageMask3.Value]);
                        g1=imfilter(app.GrayRawImage,h1);
                        g2=imfilter(app.GrayRawImage,h2);
                        g3=imfilter(app.GrayRawImage,h3);
                        
                        title1 = strcat(num2str(app.AverageMask1.Value),'X',num2str(app.AverageMask1.Value),' mean filter mask');
                        title2 = strcat(num2str(app.AverageMask2.Value),'X',num2str(app.AverageMask2.Value),' mean filter mask');
                        title3 = strcat(num2str(app.AverageMask3.Value),'X',num2str(app.AverageMask3.Value),' mean filter mask');
    
                    case 'Median'
                        g1 = medfilt2(app.GrayRawImage, [app.MedianMask1.Value,app.MedianMask1.Value]);
                        g2 = medfilt2(app.GrayRawImage, [app.MedianMask2.Value,app.MedianMask2.Value]);
                        g3 = medfilt2(app.GrayRawImage, [app.MedianMask3.Value,app.MedianMask3.Value]);
                        title1 = strcat(num2str(app.MedianMask1.Value),'X',num2str(app.MedianMask1.Value),' Median filter mask');
                        title2 = strcat(num2str(app.MedianMask2.Value),'X',num2str(app.MedianMask2.Value),' Median filter mask');
                        title3 = strcat(num2str(app.MedianMask3.Value),'X',num2str(app.MedianMask3.Value),' Median filter mask');
                        
                    case 'Gaussian'
                        
                        g1 = imgaussfilt(app.GrayRawImage,app.GaussianSigma1.Value);
                        g2 = imgaussfilt(app.GrayRawImage,app.GaussianSigma2.Value);
                        g3 = imgaussfilt(app.GrayRawImage,app.GaussianSigma3.Value);
                        title1 = strcat('Gaussian sigma ',num2str(app.GaussianSigma1.Value));
                        title2 = strcat('Gaussian sigma ',num2str(app.GaussianSigma2.Value));
                        title3 = strcat('Gaussian sigma ',num2str(app.GaussianSigma3.Value));
                       
                    case 'Laplacian'
                        h1=fspecial('laplacian',0.1);
                        h2=fspecial('laplacian',0.2);
                        h3=fspecial('laplacian',0.5);
                        g1=imfilter(app.GrayRawImage,h1);
                        g2=imfilter(app.GrayRawImage,h2);
                        g3=imfilter(app.GrayRawImage,h3);
                        title1 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha1.Value));
                        title2 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha2.Value));
                        title3 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha3.Value));
                   
                    case 'Sobel'
                        h1 = fspecial('sobel');
                        g1 = imfilter(app.GrayRawImage,h1);
                        g2 = [];
                        g3 = [];
                        title1 = 'Sobel filtering';
                        title2 = '';
                        title3 = '';
                        
                    case 'Prewitt'
                        h1 = fspecial('prewitt');
                        g1 = imfilter(app.GrayRawImage,h1);
                        g2 = [];
                        g3 = [];
                        title1 = 'Prewitt filtering';
                        title2 = '';
                        title3 = '';
                    
                    case 'ContraHarm'
                        
                        g1 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask1.Value);
                        g2 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask2.Value);
                        g3 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask3.Value);
                        title1 = strcat(num2str(app.ContraharmMask1.Value),'X',num2str(app.ContraharmMask1.Value),' ContraHarm filter mask');
                        title2 = strcat(num2str(app.ContraharmMask2.Value),'X',num2str(app.ContraharmMask2.Value),' ContraHarm filter mask');
                        title3 = strcat(num2str(app.ContraharmMask3.Value),'X',num2str(app.ContraharmMask3.Value),' ContraHarm filter mask');
                    otherwise
                        
                end
                
                %Display filtered images
                imshow(app.GrayRawImage,'parent',app.UIAxes12);
                app.UIAxes12.Title.String = 'Original';
                
                imshow(g1,'parent',app.UIAxes13);
                app.UIAxes13.Title.String = title1;
                if(size(g2) ~= 0)
                    imshow(g2,'parent',app.UIAxes14);
                else
                    cla(app.UIAxes14,'reset');
                end
                app.UIAxes14.Title.String = title2;
                if(size(g3) ~= 0)
                    imshow(g3,'parent',app.UIAxes15);
                else
                    cla(app.UIAxes15,'reset');
                end
                app.UIAxes15.Title.String = title3;
            elseif(SelectionNum > 1)
                img_index = 1;
                if(app.AverageCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1=fspecial('average',[app.AverageMask1.Value,app.AverageMask1.Value]);
                    h2=fspecial('average',[app.AverageMask2.Value,app.AverageMask2.Value]);
                    h3=fspecial('average',[app.AverageMask3.Value,app.AverageMask3.Value]);
                    g1=imfilter(app.GrayRawImage,h1);
                    g2=imfilter(app.GrayRawImage,h2);
                    g3=imfilter(app.GrayRawImage,h3);
                    
                    title1 = strcat(num2str(app.AverageMask1.Value),'X',num2str(app.AverageMask1.Value),' mean filter mask');
                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    title2 = strcat(num2str(app.AverageMask2.Value),'X',num2str(app.AverageMask2.Value),' mean filter mask');
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;
                    
                    title3 = strcat(num2str(app.AverageMask3.Value),'X',num2str(app.AverageMask3.Value),' mean filter mask');
                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.MedianCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    g1 = medfilt2(app.GrayRawImage, [app.MedianMask1.Value,app.MedianMask1.Value]);
                    g2 = medfilt2(app.GrayRawImage, [app.MedianMask2.Value,app.MedianMask2.Value]);
                    g3 = medfilt2(app.GrayRawImage, [app.MedianMask3.Value,app.MedianMask3.Value]);
                    title1 = strcat(num2str(app.MedianMask1.Value),'X',num2str(app.MedianMask1.Value),' Median filter mask');
                    title2 = strcat(num2str(app.MedianMask2.Value),'X',num2str(app.MedianMask2.Value),' Median filter mask');
                    title3 = strcat(num2str(app.MedianMask3.Value),'X',num2str(app.MedianMask3.Value),' Median filter mask');

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.GaussianCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    g1 = imgaussfilt(app.GrayRawImage,app.GaussianSigma1.Value);
                    g2 = imgaussfilt(app.GrayRawImage,app.GaussianSigma2.Value);
                    g3 = imgaussfilt(app.GrayRawImage,app.GaussianSigma3.Value);
                    title1 = strcat('Gaussian sigma ',num2str(app.GaussianSigma1.Value));
                    title2 = strcat('Gaussian sigma ',num2str(app.GaussianSigma2.Value));
                    title3 = strcat('Gaussian sigma ',num2str(app.GaussianSigma3.Value));

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.LaplacianCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1=fspecial('laplacian',0.1);
                    h2=fspecial('laplacian',0.2);
                    h3=fspecial('laplacian',0.5);
                    g1=imfilter(app.GrayRawImage,h1);
                    g2=imfilter(app.GrayRawImage,h2);
                    g3=imfilter(app.GrayRawImage,h3);
                    title1 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha1.Value));
                    title2 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha2.Value));
                    title3 = strcat('Laplacian alpha ',num2str(app.LaplacianAlpha3.Value));

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
                if(app.SobelCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1 = fspecial('sobel');
                    g1 = imfilter(app.GrayRawImage,h1);
                    g2 = [];
                    g3 = [];
                    title1 = 'Sobel filtering';
                    title2 = '';
                    title3 = '';

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    %subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    %subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                if(app.PrewittCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    h1 = fspecial('prewitt');
                    g1 = imfilter(app.GrayRawImage,h1);
                    g2 = [];
                    g3 = [];
                    title1 = 'Prewitt filtering';
                    title2 = '';
                    title3 = '';

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    %subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    %subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                if(app.ContraharmCheckBox.Value == 1)
                    subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('Original');
                    img_index = img_index + 1;
                    
                    g1 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask1.Value);
                    g2 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask2.Value);
                    g3 = ContraHarmFiltering(app, app.GrayRawImage, app.ContraharmQ.Value, app.ContraharmMask3.Value);
                    title1 = strcat(num2str(app.ContraharmMask1.Value),'X',num2str(app.ContraharmMask1.Value),' ContraHarm filter mask');
                    title2 = strcat(num2str(app.ContraharmMask2.Value),'X',num2str(app.ContraharmMask2.Value),' ContraHarm filter mask');
                    title3 = strcat(num2str(app.ContraharmMask3.Value),'X',num2str(app.ContraharmMask3.Value),' ContraHarm filter mask');

                    subplot(SelectionNum,4,img_index), imshow(g1,[]),title(title1);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g2,[]),title(title2);
                    img_index = img_index + 1;

                    subplot(SelectionNum,4,img_index), imshow(g3,[]),title(title3);
                    img_index = img_index + 1;
                end
                
            end
                
        end

        % Button pushed function: FDFilterButton
        function FDFilterButtonPushed(app, event)
            f=im2double(app.GrayRawImage);
            f=im2double(f);
            
            %计算填充图像大小
            [M,N]=size(f);
            P=2*M;
            Q=2*N;
            
            %傅里叶变换
            F=fftshift(fft2(f,P,Q));
            fft_img=mat2gray(log(1+abs(F)));
            
            %生成网格坐标
            u = -N:N-1;
            v = -M:M-1;
            [U,V] = meshgrid(u,v);
            
            %设计滤波器
            D = hypot(U,V);


            [SelectionNum, SelectedFunc] = CheckFDFilteringFuncSelection(app);
            
            if (SelectionNum == 1)
                switch SelectedFunc
                    case 'IdealLowPass'
                        D0 = app.IdealLowPassD0.Value; %截止频率
                        
                        H = mat2gray(D <= D0); %理想低通滤波器
                        title_H = 'IdealLowPassFilter';
                        title_g = 'After IdealLowPassFiltering';
                    case 'GaussianLowPass'
                        D0 = app.GaussianLowPassD0.Value; %截止频率
                        
                        H = exp((-D.^2)/(2*(D0).^2));%高斯低通滤波器
                        title_H = 'GaussianLowPassFilter';
                        title_g = 'After GaussianLowPassFiltering';
     
                    case 'ButterworthLowPass'
                        D0 = app.ButterworthLowPassD0.Value; %截止频率
                        n = app.ButterworthLowPassn.Value; %巴特沃斯滤波器阶数
                        
                        H = mat2gray(1./(1+((D./D0).^(2*n)))); %巴特沃斯低通滤波器
                        title_H = 'ButterworthLowPassFilter';
                        title_g = 'After ButterworthLowPassFiltering';
                        
                    case 'IdealHighPass'
                        D0 = app.IdealHighPassD0.Value; %截止频率
                        
                        H = mat2gray(D > D0); %理想高通滤波器
                        title_H = 'IdealHighPassFilter';
                        title_g = 'After IdealHighPassFiltering';
                        
                    case 'GaussianHighPass'
                        D0 = app.GaussianHighPassD0.Value; %截止频率
                        
                        H = 1-exp((-D.^2)/(2*(D0).^2));%高斯高通滤波器
                        title_H = 'GaussianHighPassFilter';
                        title_g = 'After GaussianHighPassFiltering';
                        
                    case 'ButterworthHighPass'
                        D0 = app.ButterworthHighPassD0.Value; %截止频率
                        n = app.ButterworthHighPassn.Value; %巴特沃斯滤波器阶数
                        
                        H = 1./(1+((D0./D).^(2*n)));%布特沃斯高通滤波器
                        title_H = 'ButterworthHighPassFilter';
                        title_g = 'After ButterworthHighPassFiltering';
                    otherwise
                        
                end
                %频域滤波
                G = F.*H;
                g0 = ifft2(fftshift(G));
                g = g0(1:M,1:N);
                g = real(g);
                
                imshow(f,'parent',app.UIAxes16);
                app.UIAxes16.Title.String = 'original';
                
                imshow(fft_img,'parent',app.UIAxes16_2);
                app.UIAxes16_2.Title.String = 'FFT';
                
                imshow(H,'parent',app.UIAxes16_3);
                app.UIAxes16_3.Title.String = title_H;
                
                imshow(g,'parent',app.UIAxes16_4);
                app.UIAxes16_4.Title.String = title_g;
            elseif (SelectionNum > 1)
                img_index = 1;
                if(app.IdealLowPassCheckBox.Value == 1)
                    D0 = app.IdealLowPassD0.Value; %截止频率
                    
                    H = mat2gray(D <= D0); %理想低通滤波器
                    title_H = 'IdealLowPassFilter';
                    title_g = 'After IdealLowPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.GaussianLowPassCheckBox.Value == 1)
                    D0 = app.GaussianLowPassD0.Value; %截止频率
                    
                    H = exp((-D.^2)/(2*(D0).^2));%高斯低通滤波器
                    title_H = 'GaussianLowPassFilter';
                    title_g = 'After GaussianLowPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.ButterworthLowPassCheckBox.Value == 1)
                    D0 = app.ButterworthLowPassD0.Value; %截止频率
                    n = app.ButterworthLowPassn.Value; %巴特沃斯滤波器阶数
                    
                    H = mat2gray(1./(1+((D./D0).^(2*n)))); %巴特沃斯低通滤波器
                    title_H = 'ButterworthLowPassFilter';
                    title_g = 'After ButterworthLowPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.IdealHighPassCheckBox.Value == 1)
                    D0 = app.IdealHighPassD0.Value; %截止频率
                    
                    H = mat2gray(D > D0); %理想高通滤波器
                    title_H = 'IdealHighPassFilter';
                    title_g = 'After IdealHighPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.GaussianHighPassCheckBox.Value == 1)
                    D0 = app.GaussianHighPassD0.Value; %截止频率
                    
                    H = 1-exp((-D.^2)/(2*(D0).^2));%高斯高通滤波器
                    title_H = 'GaussianHighPassFilter';
                    title_g = 'After GaussianHighPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
                if(app.ButterworthHighPassCheckBox.Value == 1)
                    D0 = app.ButterworthHighPassD0.Value; %截止频率
                    n = app.ButterworthHighPassn.Value; %巴特沃斯滤波器阶数
                    
                    H = 1./(1+((D0./D).^(2*n)));%布特沃斯高通滤波器
                    title_H = 'ButterworthHighPassFilter';
                    title_g = 'After ButterworthHighPassFiltering';
                    %频域滤波
                    G = F.*H;
                    g0 = ifft2(fftshift(G));
                    g = g0(1:M,1:N);
                    g = real(g);
                    
                    subplot(SelectionNum,4,img_index), imshow(f,[]),title('original');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(fft_img,[]),title('FFT');
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(H,[]),title(title_H);
                    img_index = img_index + 1;
                    
                    subplot(SelectionNum,4,img_index), imshow(g,[]),title(title_g);
                    img_index = img_index + 1;
                    
                end
            end


        end

        % Button pushed function: NoiseReduceButton
        function NoiseReduceButtonPushed(app, event)
            [NoiseFuncNum, SelectedNoiseFunc] = CheckNoiseAdditionFunction(app);
            noise = zeros(app.ImageRowNum,app.ImageColumnNum);
            if(NoiseFuncNum == 1)
                switch SelectedNoiseFunc
                    case 'GaussianNoise'
                        noise = imnoise2(app,"gaussian",app.ImageRowNum,app.ImageColumnNum,app.GaussianNoisemean.Value,app.GaussianNoiseVar.Value);
           
                    case 'SaltpepperNoise'
                        noise = imnoise2(app,"salt & pepper",app.ImageRowNum,app.ImageColumnNum,app.SaltPepperPa.Value,app.SaltPepperPa.Value);

                    case 'RayleighNoise'
                        noise = imnoise2(app,"rayleigh",app.ImageRowNum,app.ImageColumnNum,app.RayleighNoiseA.Value,app.RayleighNoiseB.Value);

                    case 'ExponentialNoise'
                        noise = imnoise2(app,"exponential",app.ImageRowNum,app.ImageColumnNum,app.ExponentialNoiseA.Value);

                    case 'ErlangNoise'
                        noise = imnoise2(app,"erlang",app.ImageRowNum,app.ImageColumnNum,app.ErlangNoiseA.Value,app.ErlangNoiseB.Value);

                    case 'LognormalNoise'
                        %noise = imnoise2(app,"lognormal",app.ImageRowNum,app.ImageColumnNum,app.ErlangNoiseA.Value,app.ErlangNoiseB);

                    otherwise
                end
                noised_img = im2double(app.GrayRawImage) + noise;
            else
                noised_img = app.GrayRawImage;
                
            end
            
            [SelectionNum, SelectedFunc] = CheckNoiseReductionFuncSelection(app);
            if (SelectionNum == 1)
                switch SelectedFunc
                    case 'Arithmetic'
                        h1=fspecial('average',[app.ArithmeticMask1.Value,app.ArithmeticMask1.Value]);
                        f1=imfilter(noised_img,h1);
 
                    case 'Geometric'
                        f1 = GeometricMeanFiltering(app,noised_img,app.GeometricMask1.Value, app.GeometricMask1.Value);
                        
                    case 'Contraharmonic'
                        f1 = ContraHarmFiltering(app, noised_img, app.ContraHarmonicQ.Value, app.ContraHarmonicMask1.Value);

                    case 'OrderStatisticsfilters'
                        f1 = ordfilt2(noised_img, app.OrderStatisticsOrder.Value, ones(app.OrderStatisticsMask.Value, app.OrderStatisticsMask.Value));
                    case 'Adaptivefilters'
                        f1 = AdaptiveMedianFiltering(app,noised_img);
                    otherwise    
                end
                imshow(app.GrayRawImage,'parent',app.UIAxes17);
                app.UIAxes17.Title.String = 'original';
                
                imshow(noise,'parent',app.UIAxes17_2);
                app.UIAxes17_2.Title.String = strcat(SelectedNoiseFunc,' noise');
                
                imshow(noised_img,'parent',app.UIAxes17_3);
                app.UIAxes17_3.Title.String = strcat('With',SelectedNoiseFunc);
                
                imshow(f1,'parent',app.UIAxes17_4);
                app.UIAxes17_4.Title.String = "After filter";
                
            elseif(SelectionNum > 1)
                img_index = 1;
                     subplot(2,SelectionNum,img_index), imshow(app.GrayRawImage,[]),title('original');
                     img_index = img_index + 1;
                     
                     subplot(2,SelectionNum,img_index), imshow(noise,[]),title(strcat(SelectedNoiseFunc,' noise'));
                     img_index = img_index + 1;
                     
                     %skip 1st row next 3 slot
                     img_index = img_index + 1;
                     img_index = img_index + 1;
                     img_index = img_index + 1;

                if(app.ArithmeticCheckBox.Value == 1)
                    SelectedFunc = 'Arithmetic';
                    h1=fspecial('average',[app.ArithmeticMask1.Value,app.ArithmeticMask1.Value]);
                    f1=imfilter(noised_img,h1);
                    
%                     subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('original');
%                     img_index = img_index + 1;
%                     
%                     subplot(SelectionNum,4,img_index), imshow(noise,[]),title(strcat(SelectedNoiseFunc,' noise'));
%                     img_index = img_index + 1;
%                     
%                     subplot(SelectionNum,4,img_index), imshow(noised_img,[]),title('Image with noise');
%                     img_index = img_index + 1;
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                    
                end
                
                if (app.GeometricCheckBox.Value == 1)
                    SelectedFunc = 'Geometric';
                    f1 = GeometricMeanFiltering(app,noised_img,app.GeometricMask1.Value, app.GeometricMask1.Value);
                    
%                     subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('original');
%                     img_index = img_index + 1;
%                     
%                     subplot(SelectionNum,4,img_index), imshow(noise,[]),title(strcat(SelectedNoiseFunc,' noise'));
%                     img_index = img_index + 1;
%                     
%                     subplot(SelectionNum,4,img_index), imshow(noised_img,[]),title('Image with noise');
%                     img_index = img_index + 1;
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                end
                
                if (app.ContraharmonicCheckBox.Value == 1)
                    SelectedFunc = 'Contraharmonic';
                    f1 = ContraHarmFiltering(app, noised_img, app.ContraHarmonicQ.Value, app.ContraHarmonicMask1.Value);
                    
%                     subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('original');
%                     img_index = img_index + 1;
%                     
%                     subplot(SelectionNum,4,img_index), imshow(noise,[]),title(strcat(SelectedNoiseFunc,' noise'));
%                     img_index = img_index + 1;
%                     
%                     subplot(SelectionNum,4,img_index), imshow(noised_img,[]),title('Image with noise');
%                     img_index = img_index + 1;
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                end
                
                if (app.OrderStatisticsCheckBox.Value == 1)
                    SelectedFunc = 'OrderStatisticsfilters';
                    f1 = ordfilt2(noised_img, app.OrderStatisticsOrder.Value, ones(app.OrderStatisticsMask.Value, app.OrderStatisticsMask.Value));
                    
%                     subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('original');
%                     img_index = img_index + 1;
%                     
%                     subplot(SelectionNum,4,img_index), imshow(noise,[]),title(strcat(SelectedNoiseFunc,' noise'));
%                     img_index = img_index + 1;
%                     
%                     subplot(SelectionNum,4,img_index), imshow(noised_img,[]),title('Image with noise');
%                     img_index = img_index + 1;
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                end
                
                if (app.AdaptivefiltersCheckBox.Value == 1)
                    SelectedFunc = 'Adaptivefilters'; 
                    
                    f1 = AdaptiveMedianFiltering(app,noised_img);
                    
%                     subplot(SelectionNum,4,img_index), imshow(app.GrayRawImage,[]),title('original');
%                     img_index = img_index + 1;
%                     
%                     subplot(SelectionNum,4,img_index), imshow(noise,[]),title(strcat(SelectedNoiseFunc,' noise'));
%                     img_index = img_index + 1;
%                     
%                     subplot(SelectionNum,4,img_index), imshow(noised_img,[]),title('Image with noise');
%                     img_index = img_index + 1;
                    
                    subplot(2,SelectionNum,img_index), imshow(f1,[]),title(strcat('After ', SelectedFunc, ' Filtering'));
                    img_index = img_index + 1;
                end
                
            end

        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            value = app.TransformationsButtonGroup.SelectedObject.Text;
           
            switch value
                case 'Negative'
   
                    x=[0:1:255];
                    y= 255-x;
                    
                    cla(app.UIAxes9,"reset");
                    cla(app.UIAxes10,"reset");
                    
                    plot(app.UIAxes9,y);
                    
                    imshow(255-app.GrayRawImage,'parent',app.UIAxes10);
                    
                case 'Logrithm'
           
                    f = 0:0.01:1;
                    v1 = 1;
                    y1 = log2(1 + v1*f)/log2(v1+1); 
                    v2 = 10;
                    y2 = log2(1 + v2*f)/log2(v2+1); 
                    v3 = 50;
                    y3 = log2(1 + v3*f)/log2(v3+1); 
                    v4 = 100;
                    y4 = log2(1 + v4*f)/log2(v4+1); 
                    v5 = 200;
                    y5 = log2(1 + v5*f)/log2(v5+1); 
                    
                    cla(app.UIAxes9,"reset");
                    cla(app.UIAxes10,"reset");
                    
                    plot(app.UIAxes9,f,y1,'g',f,y2,'r',f,y3,'b',f,y4,'c',f,y5,'y');
                    
                    title(app.UIAxes9,'Log transformation: g = log(1+v*r)/log(1+v)');
            
                    lgd = legend(app.UIAxes9,'Log(v=1)','Log(v=10)','Log(v=50)','Log(v=100)','Log(v=200)');
                    legend(app.UIAxes9,'Location','southeast');
                    %title(app.UIAxes9,lgd,'The Legend Title')

                    LogTransform(app);

                    
                case 'PowerLaw'
                    
                    app.Piecewise_ax.Value = 1;
                    
                    C = 1;
                    
                    f = 0:0.01:1;
                    Gamma = 0.1;
                    y1 = C*(f.^Gamma); 
                    Gamma = 0.2;
                    y2 =  C*(f.^Gamma); 
                    Gamma = 0.4;
                    y3 =  C*(f.^Gamma); 
                    Gamma = 2.5;
                    y4 =  C*(f.^Gamma); 
                    Gamma = 5;
                    y5 =  C*(f.^Gamma); 
                    Gamma = 20;
                    y6 =  C*(f.^Gamma);
                    
                    cla(app.UIAxes9,"reset");
                    cla(app.UIAxes10,"reset");
                    plot(app.UIAxes9,f,y1,'g',f,y2,'r',f,y3,'b',f,y4,'c',f,y5,'y',f,y6,'m');   
                    
                    title(app.UIAxes9,'PowerLaw transformation: g = r^Gamma');
                    
                    lgd = legend(app.UIAxes9,'Gamma=0.1','Gamma=0.2','Gamma=0.4','Gamma=2.5','Gamma=5','Gamma=20');
                    legend(app.UIAxes9,'Location','southeast');
                    
                    PowerLawTransform(app);
                    
                case 'Piecewise-linear'

                    cla(app.UIAxes9,"reset");
                    cla(app.UIAxes10,"reset");
                    
                    PieceWiseLinearTransform(app);
                    
                case 'Histogram'
                    cla(app.UIAxes9,"reset");
                    cla(app.UIAxes10,"reset");
                    g=histeq(app.GrayRawImage);
                    imshow(g,'parent',app.UIAxes9);
                    histogram(app.UIAxes10,g);

                    
                otherwise
                    
                   
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageMenuSelected, true);
            app.LoadImageMenu.Text = 'LoadImage';
            app.SaveImageMenu_2 = uimenu(app.FileMenu);
            app.SaveImageMenu_2.Text = 'SaveImage';
            app.FunctionsMenu = uimenu(app.UIFigure);
            app.FunctionsMenu.Text = 'Functions';
            app.Menu = uimenu(app.FunctionsMenu);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GrayRawImgAxis = uiaxes(app.GeneralTab);
            title(app.GrayRawImgAxis, '')
            xlabel(app.GrayRawImgAxis, '')
            ylabel(app.GrayRawImgAxis, '')
            app.GrayRawImgAxis.XTick = [];
            app.GrayRawImgAxis.XTickLabel = '';
            app.GrayRawImgAxis.YTick = [];
            app.GrayRawImgAxis.YTickLabel = '';
            app.Histogram = uiaxes(app.GeneralTab);
            title(app.Histogram, '')
            xlabel(app.Histogram, '')
            ylabel(app.Histogram, '')
            app.Histogram.XLim = [0 255];
            app.Histogram.XTick = [];
            app.Histogram.YTick = [];
            app.ImageSizeLabel = uilabel(app.GeneralTab);
            app.ImageSizeLabel.Text = 'ImageSize';
            app.ImageSize = uieditfield(app.GeneralTab, 'text');
            app.ImageMeanEditFieldLabel = uilabel(app.GeneralTab);
            app.ImageMeanEditFieldLabel.Text = 'ImageMean';
            app.ImageMean = uieditfield(app.GeneralTab, 'text');
            app.StandardDeviationEditFieldLabel = uilabel(app.GeneralTab);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.StandardDeviation = uieditfield(app.GeneralTab, 'text');
            app.GrayLevelsTab = uitab(app.TabGroup);
            app.GrayLevelsTab.Title = 'Gray Levels';
            app.ReduceGrayLevelButton = uibutton(app.GrayLevelsTab, 'push');
            app.ReduceGrayLevelButton.ButtonPushedFcn = createCallbackFcn(app, @ReduceGrayLevelButtonPushed, true);
            app.ReduceGrayLevelButton.Text = 'Reduce Gray Level';
            app.ShowInFigure = uicheckbox(app.GrayLevelsTab);
            app.ShowInFigure.Text = 'Show In new Window';
            app.UIAxes = uiaxes(app.GrayLevelsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.GrayLevelsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.GrayLevelsTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.GrayLevelsTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes5 = uiaxes(app.GrayLevelsTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes6 = uiaxes(app.GrayLevelsTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.UIAxes7 = uiaxes(app.GrayLevelsTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, '')
            ylabel(app.UIAxes7, '')
            app.UIAxes7.XTick = [];
            app.UIAxes7.YTick = [];
            app.UIAxes8 = uiaxes(app.GrayLevelsTab);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.ResizeTab = uitab(app.TabGroup);
            app.ResizeTab.Title = 'Resize';
            app.TimesEditFieldLabel = uilabel(app.ResizeTab);
            app.TimesEditFieldLabel.Text = 'Times';
            app.TimesEditField = uieditfield(app.ResizeTab, 'numeric');
            app.TimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimesEditFieldValueChanged, true);
            app.TimesEditField.Value = 1;
            app.UIAxes18 = uiaxes(app.ResizeTab);
            title(app.UIAxes18, 'Title')
            xlabel(app.UIAxes18, '')
            ylabel(app.UIAxes18, '')
            app.UIAxes18.XTick = [];
            app.UIAxes18.YTick = [];
            app.UIAxes18_2 = uiaxes(app.ResizeTab);
            title(app.UIAxes18_2, 'Title')
            xlabel(app.UIAxes18_2, '')
            ylabel(app.UIAxes18_2, '')
            app.UIAxes18_2.XTick = [];
            app.UIAxes18_2.YTick = [];
            app.UIAxes18_3 = uiaxes(app.ResizeTab);
            title(app.UIAxes18_3, 'Title')
            xlabel(app.UIAxes18_3, '')
            ylabel(app.UIAxes18_3, '')
            app.UIAxes18_3.XTick = [];
            app.UIAxes18_3.YTick = [];
            app.IntensityTransformationTab = uitab(app.TabGroup);
            app.IntensityTransformationTab.Title = 'Intensity Transformation';
            app.UIAxes9 = uiaxes(app.IntensityTransformationTab);
            title(app.UIAxes9, 'Transformation')
            xlabel(app.UIAxes9, '')
            ylabel(app.UIAxes9, '')
            app.UIAxes10 = uiaxes(app.IntensityTransformationTab);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, '')
            ylabel(app.UIAxes10, '')
            app.TransformationsButtonGroup = uibuttongroup(app.IntensityTransformationTab);
            app.TransformationsButtonGroup.Title = 'Transformations';
            app.NegativeButton = uiradiobutton(app.TransformationsButtonGroup);
            app.NegativeButton.Text = 'Negative';
            app.NegativeButton.Value = true;
            app.LogrithmButton = uiradiobutton(app.TransformationsButtonGroup);
            app.LogrithmButton.Text = 'Logrithm';
            app.PowerLawButton = uiradiobutton(app.TransformationsButtonGroup);
            app.PowerLawButton.Text = 'PowerLaw';
            app.PiecewiselinearButton = uiradiobutton(app.TransformationsButtonGroup);
            app.PiecewiselinearButton.Text = 'Piecewise-linear';
            app.HistogramButton = uiradiobutton(app.TransformationsButtonGroup);
            app.HistogramButton.Text = 'Histogram';
            app.Label = uilabel(app.TransformationsButtonGroup);
            app.Label.Text = 'γ';
            app.PowerLawY = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.PowerLawY.Value = 2;
            app.vEditFieldLabel = uilabel(app.TransformationsButtonGroup);
            app.vEditFieldLabel.Text = 'v';
            app.LogV = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.LogV.Value = 10;
            app.Piecewise_ay = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.Piecewise_ay.ValueChangedFcn = createCallbackFcn(app, @Piecewise_ayValueChanged, true);
            app.Piecewise_ay.Value = 30;
            app.aLabel = uilabel(app.TransformationsButtonGroup);
            app.aLabel.Text = 'a';
            app.Piecewise_ax = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.Piecewise_ax.ValueChangedFcn = createCallbackFcn(app, @Piecewise_axValueChanged, true);
            app.Piecewise_ax.Value = 50;
            app.Piecewise_bx = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.Piecewise_bx.ValueChangedFcn = createCallbackFcn(app, @Piecewise_bxValueChanged, true);
            app.Piecewise_bx.Value = 170;
            app.Piecewise_by = uieditfield(app.TransformationsButtonGroup, 'numeric');
            app.Piecewise_by.ValueChangedFcn = createCallbackFcn(app, @Piecewise_byValueChanged, true);
            app.Piecewise_by.Value = 220;
            app.bLabel = uilabel(app.TransformationsButtonGroup);
            app.bLabel.Text = 'b';
            app.TransformButton = uibutton(app.IntensityTransformationTab, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Text = 'Transform';
            app.SDFilteringTab = uitab(app.TabGroup);
            app.SDFilteringTab.Title = 'SD Filtering';
            app.FilterTypeDropDownLabel = uilabel(app.SDFilteringTab);
            app.FilterTypeDropDownLabel.Visible = 'off';
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.SDFilteringTab);
            app.FilterTypeDropDown.Items = {'Average', 'Median', 'Gaussian', 'Laplacian', 'Prewitt', 'Sobel', 'ContraHarm'};
            app.FilterTypeDropDown.Visible = 'off';
            app.FilterTypeDropDown.Value = 'Average';
            app.SDFilterButton = uibutton(app.SDFilteringTab, 'push');
            app.SDFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SDFilterButtonPushed, true);
            app.SDFilterButton.Text = 'SD Filter';
            app.SmoothingPanel = uipanel(app.SDFilteringTab);
            app.SmoothingPanel.Title = 'Smoothing';
            app.AverageCheckBox = uicheckbox(app.SmoothingPanel);
            app.AverageCheckBox.Text = 'Average';
            app.MedianCheckBox = uicheckbox(app.SmoothingPanel);
            app.MedianCheckBox.Text = 'Median';
            app.GaussianCheckBox = uicheckbox(app.SmoothingPanel);
            app.GaussianCheckBox.Text = 'Gaussian';
            app.ContraharmCheckBox = uicheckbox(app.SmoothingPanel);
            app.ContraharmCheckBox.Visible = 'off';
            app.ContraharmCheckBox.Text = 'Contraharm';
            app.AverageMask1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.AverageMask1.Value = 3;
            app.AverageMask2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.AverageMask2.Value = 7;
            app.AverageMask3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.AverageMask3.Value = 9;
            app.MedianMask1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.MedianMask1.Value = 3;
            app.MedianMask2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.MedianMask2.Value = 4;
            app.MedianMask3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.MedianMask3.Value = 5;
            app.GaussianSigma1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.GaussianSigma1.Value = 0.5;
            app.GaussianSigma2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.GaussianSigma2.Value = 1;
            app.GaussianSigma3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.GaussianSigma3.Value = 4;
            app.ContraharmMask1 = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmMask1.Visible = 'off';
            app.ContraharmMask1.Value = 3;
            app.ContraharmMask2 = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmMask2.Visible = 'off';
            app.ContraharmMask2.Value = 7;
            app.ContraharmMask3 = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmMask3.Visible = 'off';
            app.ContraharmMask3.Value = 9;
            app.QEditFieldLabel = uilabel(app.SmoothingPanel);
            app.QEditFieldLabel.Visible = 'off';
            app.QEditFieldLabel.Text = 'Q';
            app.ContraharmQ = uieditfield(app.SmoothingPanel, 'numeric');
            app.ContraharmQ.Visible = 'off';
            app.MaskLabel = uilabel(app.SmoothingPanel);
            app.MaskLabel.Visible = 'off';
            app.MaskLabel.Text = 'Mask';
            app.MaskLabel_2 = uilabel(app.SmoothingPanel);
            app.MaskLabel_2.Text = 'Mask';
            app.MaskLabel_3 = uilabel(app.SmoothingPanel);
            app.MaskLabel_3.Text = 'Mask';
            app.SigmaLabel = uilabel(app.SmoothingPanel);
            app.SigmaLabel.Text = 'Sigma';
            app.SharpeningPanel = uipanel(app.SDFilteringTab);
            app.SharpeningPanel.Title = 'Sharpening';
            app.LaplacianCheckBox = uicheckbox(app.SharpeningPanel);
            app.LaplacianCheckBox.Text = 'Laplacian';
            app.PrewittCheckBox = uicheckbox(app.SharpeningPanel);
            app.PrewittCheckBox.Text = 'Prewitt';
            app.SobelCheckBox = uicheckbox(app.SharpeningPanel);
            app.SobelCheckBox.Text = 'Sobel';
            app.LaplacianAlpha1 = uieditfield(app.SharpeningPanel, 'numeric');
            app.LaplacianAlpha1.Value = 0.1;
            app.LaplacianAlpha2 = uieditfield(app.SharpeningPanel, 'numeric');
            app.LaplacianAlpha2.Value = 0.2;
            app.LaplacianAlpha3 = uieditfield(app.SharpeningPanel, 'numeric');
            app.LaplacianAlpha3.Value = 0.5;
            app.AlphaLabel = uilabel(app.SharpeningPanel);
            app.AlphaLabel.Text = 'Alpha';
            app.UIAxes12 = uiaxes(app.SDFilteringTab);
            title(app.UIAxes12, 'Title')
            xlabel(app.UIAxes12, '')
            ylabel(app.UIAxes12, '')
            app.UIAxes12.XTick = [];
            app.UIAxes12.YTick = [];
            app.UIAxes13 = uiaxes(app.SDFilteringTab);
            title(app.UIAxes13, 'Title')
            xlabel(app.UIAxes13, '')
            ylabel(app.UIAxes13, '')
            app.UIAxes13.XTick = [];
            app.UIAxes13.YTick = [];
            app.UIAxes14 = uiaxes(app.SDFilteringTab);
            title(app.UIAxes14, 'Title')
            xlabel(app.UIAxes14, '')
            ylabel(app.UIAxes14, '')
            app.UIAxes14.XTick = [];
            app.UIAxes14.YTick = [];
            app.UIAxes15 = uiaxes(app.SDFilteringTab);
            title(app.UIAxes15, 'Title')
            xlabel(app.UIAxes15, '')
            ylabel(app.UIAxes15, '')
            app.UIAxes15.XTick = [];
            app.UIAxes15.YTick = [];
            app.FDFilteringTab = uitab(app.TabGroup);
            app.FDFilteringTab.Title = 'FD Filtering';
            app.FilterTypeDropDown_2Label = uilabel(app.FDFilteringTab);
            app.FilterTypeDropDown_2Label.Visible = 'off';
            app.FilterTypeDropDown_2Label.Text = 'Filter Type';
            app.FilterTypeDropDown_2 = uidropdown(app.FDFilteringTab);
            app.FilterTypeDropDown_2.Items = {'IdealLowPass', 'GuassianLowPass', 'ButterworthLowPass', 'IdealHighPass', 'GuassianHighPass', 'ButterworthHighPass'};
            app.FilterTypeDropDown_2.Visible = 'off';
            app.FilterTypeDropDown_2.Value = 'IdealLowPass';
            app.FDFilterButton = uibutton(app.FDFilteringTab, 'push');
            app.FDFilterButton.ButtonPushedFcn = createCallbackFcn(app, @FDFilterButtonPushed, true);
            app.FDFilterButton.Text = 'FD Filter';
            app.SmoothingPanel_2 = uipanel(app.FDFilteringTab);
            app.SmoothingPanel_2.Title = 'Smoothing';
            app.IdealLowPassCheckBox = uicheckbox(app.SmoothingPanel_2);
            app.IdealLowPassCheckBox.Text = 'IdealLowPass';
            app.GaussianLowPassCheckBox = uicheckbox(app.SmoothingPanel_2);
            app.GaussianLowPassCheckBox.Text = 'GaussianLowPass';
            app.ButterworthLowPassCheckBox = uicheckbox(app.SmoothingPanel_2);
            app.ButterworthLowPassCheckBox.Text = 'ButterworthLowPass';
            app.D0EditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.D0EditFieldLabel.Text = 'D0';
            app.IdealLowPassD0 = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.IdealLowPassD0.Value = 30;
            app.D0EditField_2Label = uilabel(app.SmoothingPanel_2);
            app.D0EditField_2Label.Text = 'D0';
            app.GaussianLowPassD0 = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.GaussianLowPassD0.Value = 30;
            app.D0EditField_3Label = uilabel(app.SmoothingPanel_2);
            app.D0EditField_3Label.Text = 'D0';
            app.ButterworthLowPassD0 = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.ButterworthLowPassD0.Value = 30;
            app.nEditFieldLabel = uilabel(app.SmoothingPanel_2);
            app.nEditFieldLabel.Text = 'n';
            app.ButterworthLowPassn = uieditfield(app.SmoothingPanel_2, 'numeric');
            app.ButterworthLowPassn.Value = 2;
            app.SharpeningPanel_2 = uipanel(app.FDFilteringTab);
            app.SharpeningPanel_2.Title = 'Sharpening';
            app.IdealHighPassCheckBox = uicheckbox(app.SharpeningPanel_2);
            app.IdealHighPassCheckBox.Text = 'IdealHighPass';
            app.GaussianHighPassCheckBox = uicheckbox(app.SharpeningPanel_2);
            app.GaussianHighPassCheckBox.Text = 'GaussianHighPass';
            app.ButterworthHighPassCheckBox = uicheckbox(app.SharpeningPanel_2);
            app.ButterworthHighPassCheckBox.Text = 'ButterworthHighPass';
            app.D0EditField_4Label = uilabel(app.SharpeningPanel_2);
            app.D0EditField_4Label.Text = 'D0';
            app.IdealHighPassD0 = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.IdealHighPassD0.Value = 30;
            app.D0EditField_5Label = uilabel(app.SharpeningPanel_2);
            app.D0EditField_5Label.Text = 'D0';
            app.GaussianHighPassD0 = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.GaussianHighPassD0.Value = 30;
            app.D0EditField_6Label = uilabel(app.SharpeningPanel_2);
            app.D0EditField_6Label.Text = 'D0';
            app.ButterworthHighPassD0 = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.ButterworthHighPassD0.Value = 30;
            app.nEditField_2Label = uilabel(app.SharpeningPanel_2);
            app.nEditField_2Label.Text = 'n';
            app.ButterworthHighPassn = uieditfield(app.SharpeningPanel_2, 'numeric');
            app.ButterworthHighPassn.Value = 2;
            app.UIAxes16 = uiaxes(app.FDFilteringTab);
            title(app.UIAxes16, 'Title')
            xlabel(app.UIAxes16, '')
            ylabel(app.UIAxes16, '')
            app.UIAxes16.XTick = [];
            app.UIAxes16.YTick = [];
            app.UIAxes16_2 = uiaxes(app.FDFilteringTab);
            title(app.UIAxes16_2, 'Title')
            xlabel(app.UIAxes16_2, '')
            ylabel(app.UIAxes16_2, '')
            app.UIAxes16_2.XTick = [];
            app.UIAxes16_2.YTick = [];
            app.UIAxes16_3 = uiaxes(app.FDFilteringTab);
            title(app.UIAxes16_3, 'Title')
            xlabel(app.UIAxes16_3, '')
            ylabel(app.UIAxes16_3, '')
            app.UIAxes16_3.XTick = [];
            app.UIAxes16_3.YTick = [];
            app.UIAxes16_4 = uiaxes(app.FDFilteringTab);
            title(app.UIAxes16_4, 'Title')
            xlabel(app.UIAxes16_4, '')
            ylabel(app.UIAxes16_4, '')
            app.UIAxes16_4.XTick = [];
            app.UIAxes16_4.YTick = [];
            app.NoiseReductionTab = uitab(app.TabGroup);
            app.NoiseReductionTab.Title = 'Noise Reduction';
            app.NoiseReductionPanel = uipanel(app.NoiseReductionTab);
            app.NoiseReductionPanel.Title = 'Noise Reduction';
            app.ArithmeticCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.ArithmeticCheckBox.Text = 'Arithmetic';
            app.GeometricCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.GeometricCheckBox.Text = 'Geometric';
            app.ContraharmonicCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.ContraharmonicCheckBox.Text = 'Contra-harmonic';
            app.OrderStatisticsCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.OrderStatisticsCheckBox.Text = 'Order-Statistics';
            app.AdaptivefiltersCheckBox = uicheckbox(app.NoiseReductionPanel);
            app.AdaptivefiltersCheckBox.Text = 'Adaptive filters';
            app.MaskEditFieldLabel = uilabel(app.NoiseReductionPanel);
            app.MaskEditFieldLabel.Text = 'Mask';
            app.ArithmeticMask1 = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.ArithmeticMask1.Value = 3;
            app.MaskEditField_2Label = uilabel(app.NoiseReductionPanel);
            app.MaskEditField_2Label.Text = 'Mask';
            app.GeometricMask1 = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.GeometricMask1.Value = 3;
            app.MaskEditField_3Label = uilabel(app.NoiseReductionPanel);
            app.MaskEditField_3Label.Text = 'Mask';
            app.ContraHarmonicMask1 = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.ContraHarmonicMask1.Value = 3;
            app.MaskEditField_4Label = uilabel(app.NoiseReductionPanel);
            app.MaskEditField_4Label.Text = 'Mask';
            app.OrderStatisticsMask = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.OrderStatisticsMask.Value = 5;
            app.OrderEditFieldLabel = uilabel(app.NoiseReductionPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderStatisticsOrder = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.OrderStatisticsOrder.Value = 13;
            app.QEditFieldLabel_2 = uilabel(app.NoiseReductionPanel);
            app.QEditFieldLabel_2.Text = 'Q';
            app.ContraHarmonicQ = uieditfield(app.NoiseReductionPanel, 'numeric');
            app.ContraHarmonicQ.Value = 2;
            app.NoiseReduceButton = uibutton(app.NoiseReductionTab, 'push');
            app.NoiseReduceButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseReduceButtonPushed, true);
            app.NoiseReduceButton.Text = 'Noise Reduce';
            app.UIAxes17 = uiaxes(app.NoiseReductionTab);
            title(app.UIAxes17, 'Title')
            xlabel(app.UIAxes17, '')
            ylabel(app.UIAxes17, '')
            app.UIAxes17.XTick = [];
            app.UIAxes17.YTick = [];
            app.UIAxes17_2 = uiaxes(app.NoiseReductionTab);
            title(app.UIAxes17_2, 'Title')
            xlabel(app.UIAxes17_2, '')
            ylabel(app.UIAxes17_2, '')
            app.UIAxes17_2.XTick = [];
            app.UIAxes17_2.YTick = [];
            app.UIAxes17_3 = uiaxes(app.NoiseReductionTab);
            title(app.UIAxes17_3, 'Title')
            xlabel(app.UIAxes17_3, '')
            ylabel(app.UIAxes17_3, '')
            app.UIAxes17_3.XTick = [];
            app.UIAxes17_3.YTick = [];
            app.UIAxes17_4 = uiaxes(app.NoiseReductionTab);
            title(app.UIAxes17_4, 'Title')
            xlabel(app.UIAxes17_4, '')
            ylabel(app.UIAxes17_4, '')
            app.UIAxes17_4.XTick = [];
            app.UIAxes17_4.YTick = [];
            app.NoiseadditionPanel = uipanel(app.NoiseReductionTab);
            app.NoiseadditionPanel.Title = 'Noise addition';
            app.GaussianNoiseCheckBox = uicheckbox(app.NoiseadditionPanel);
            app.GaussianNoiseCheckBox.Text = 'GaussianNoise';
            app.meanEditFieldLabel = uilabel(app.NoiseadditionPanel);
            app.meanEditFieldLabel.Text = 'mean';
            app.GaussianNoisemean = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.varianceEditFieldLabel = uilabel(app.NoiseadditionPanel);
            app.varianceEditFieldLabel.Text = 'variance';
            app.GaussianNoiseVar = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.GaussianNoiseVar.Value = 0.1;
            app.SaltpepperNoiseCheckBox = uicheckbox(app.NoiseadditionPanel);
            app.SaltpepperNoiseCheckBox.Text = 'Salt&pepperNoise';
            app.PaLabel = uilabel(app.NoiseadditionPanel);
            app.PaLabel.Text = 'Pa';
            app.SaltPepperPa = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.SaltPepperPa.Value = 0.1;
            app.RayleighNoiseCheckBox = uicheckbox(app.NoiseadditionPanel);
            app.RayleighNoiseCheckBox.Text = 'RayleighNoise';
            app.ExponentialNoiseCheckBox = uicheckbox(app.NoiseadditionPanel);
            app.ExponentialNoiseCheckBox.Text = 'ExponentialNoise';
            app.ErlangNoiseCheckBox = uicheckbox(app.NoiseadditionPanel);
            app.ErlangNoiseCheckBox.Text = 'ErlangNoise';
            app.aEditFieldLabel = uilabel(app.NoiseadditionPanel);
            app.aEditFieldLabel.Text = 'a';
            app.RayleighNoiseA = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.NoiseadditionPanel);
            app.bEditFieldLabel.Text = 'b';
            app.RayleighNoiseB = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.RayleighNoiseB.Value = 1;
            app.aEditField_2Label = uilabel(app.NoiseadditionPanel);
            app.aEditField_2Label.Text = 'a';
            app.ExponentialNoiseA = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.ExponentialNoiseA.Value = 1;
            app.aEditField_3Label = uilabel(app.NoiseadditionPanel);
            app.aEditField_3Label.Text = 'a';
            app.ErlangNoiseA = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.ErlangNoiseA.Value = 2;
            app.bEditField_2Label = uilabel(app.NoiseadditionPanel);
            app.bEditField_2Label.Text = 'b';
            app.ErlangNoiseB = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.ErlangNoiseB.Value = 5;
            app.PbLabel = uilabel(app.NoiseadditionPanel);
            app.PbLabel.Text = 'Pb';
            app.SaltPepperPb = uieditfield(app.NoiseadditionPanel, 'numeric');
            app.SaltPepperPb.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end